classdef Equalizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        HighSlider                    matlab.ui.control.Slider
        HighSliderLabel               matlab.ui.control.Label
        StopButton_2                  matlab.ui.control.Button
        PauseResumeButton_2           matlab.ui.control.Button
        PlayButton_2                  matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        PauseResumeButton             matlab.ui.control.Button
        PlayButton                    matlab.ui.control.Button
        ExportButton                  matlab.ui.control.Button
        MATLABGUIAUDIOEQUALIZERLabel  matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        FileNameArea                  matlab.ui.control.TextArea
        Label                         matlab.ui.control.Label
        UpperMidrangeSlider           matlab.ui.control.Slider
        UpperMidrangeSliderLabel      matlab.ui.control.Label
        MidrangeSlider                matlab.ui.control.Slider
        Band24kHzLabel                matlab.ui.control.Label
        LowMidrangeSlider             matlab.ui.control.Slider
        Band052kHzLabel               matlab.ui.control.Label
        LowSlider                     matlab.ui.control.Slider
        Band250500HzLabel             matlab.ui.control.Label
        HighEditField                 matlab.ui.control.NumericEditField
        UpMidEditField                matlab.ui.control.NumericEditField
        MidEditField                  matlab.ui.control.NumericEditField
        LowMidEditField               matlab.ui.control.NumericEditField
        LowEditField                  matlab.ui.control.NumericEditField
        SuperLowEditField             matlab.ui.control.NumericEditField
        SuperLowSlider                matlab.ui.control.Slider
        Low250HzLabel                 matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        file ;
        path ;
        ori_player ;
        mod_player ;
        y1 ;
        Fs1 ;
        y2 ;
        Fs2 ;
        ori_player_state = "0";
        mod_player_state = "0";
        mod = "0" ;
        SuperLow_Gain = 0 ;
        Low_Gain = 0 ;
        LowerMid_Gain = 0 ;
        Mid_Gain = 0 ;
        UpperMid_Gain = 0 ;
        High_Gain = 0 ;

        yx2 ;
        yx3 ;
        yx4 ;
        yx5 ;
        yx6 ;
        yx7 ;

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Gainchange(app)

            plot(app.UIAxes3,[1 2 3 4 5 6 7 8],[0 app.SuperLow_Gain app.Low_Gain app.LowerMid_Gain app.Mid_Gain app.UpperMid_Gain app.High_Gain 0]);
            
        end

        % Callback function: BrowseButton, FileNameArea
        function BrowseButtonPushed(app, event)

            [app.file,app.path] = uigetfile({'*.wav'},'Select file .wav')
            app.FileNameArea.Value = app.file
            
            [app.y1, app.Fs1] = audioread(fullfile(app.path,app.file));
            app.ori_player = audioplayer(app.y1, app.Fs1);


            %app.FileNameArea.Value = num2str(Fs1);        % Display sample rate in label
            plot(app.UIAxes, app.y1);
            app.mod = "1";
            DrawOutput(app)
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            play(app.ori_player) 
            app.ori_player_state = "1" ;
            PauseResumeButtonPushed(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.ori_player) 
            app.ori_player_state = "0" ;
            PauseResumeButtonPushed(app)
        end

        % Button pushed function: PauseResumeButton
        function PauseResumeButtonPushed(app, event)
        if app.ori_player_state == "0"
           app.PauseResumeButton.Text = "Pause/Resume" ;
        elseif app.ori_player_state == "2"
           pause(app.ori_player)
           app.ori_player_state = "1"
           app.PauseResumeButton.Text = "Resume" ;  
        elseif app.ori_player_state == "1"
           resume(app.ori_player)
           app.ori_player_state = "2"
           app.PauseResumeButton.Text = "Pause" ;
        end
        end

        % Button pushed function: PauseResumeButton_2
        function PauseResumeButton_2Pushed(app, event)
        if app.mod_player_state == "0"
           app.PauseResumeButton_2.Text = "Pause/Resume" ;
        elseif app.mod_player_state == "2"
           pause(app.mod_player)
           app.mod_player_state = "1"
           app.PauseResumeButton_2.Text = "Resume" ;  
        elseif app.mod_player_state == "1"
           resume(app.mod_player)
           app.mod_player_state = "2"
           app.PauseResumeButton_2.Text = "Pause" ;
        end
        end

        % Button pushed function: PlayButton_2
        function PlayButton_2Pushed(app, event)
            play(app.mod_player) 
            app.mod_player_state = "1" ;
            PauseResumeButton_2Pushed(app)
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            stop(app.mod_player) 
            app.mod_player_state = "0" ;
            PauseResumeButton_2Pushed(app)
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [file,path] = uiputfile({'*.wav'},'Save As');
            newfile = fullfile(path,file) ;
            audiowrite(newfile,app.y2,app.Fs2);
        end

        % Value changed function: HighSlider, LowMidrangeSlider, 
        % ...and 4 other components
        function SuperLowSliderValueChanged(app, event)
           app.SuperLow_Gain = app.SuperLowSlider.Value;
           app.Low_Gain = app.LowSlider.Value;
           app.LowerMid_Gain = app.LowMidrangeSlider.Value;
           app.Mid_Gain = app.MidrangeSlider.Value;
           app.UpperMid_Gain = app.UpperMidrangeSlider.Value;
           app.High_Gain = app.HighSlider.Value;

           app.SuperLowEditField.Value = app.SuperLow_Gain;
           app.LowEditField.Value = app.Low_Gain;
           app.LowMidEditField.Value = app.LowerMid_Gain;
           app.MidEditField.Value = app.Mid_Gain;
           app.UpMidEditField.Value = app.UpperMid_Gain;
           app.HighEditField.Value = app.High_Gain;
           Gainchange(app)
           DrawOutput(app)


        end

        % Value changed function: HighEditField, LowEditField, 
        % ...and 4 other components
        function SuperLowEditFieldValueChanged(app, event)
           app.SuperLow_Gain = app.SuperLowEditField.Value; 
           app.Low_Gain = app.LowEditField.Value;
           app.LowerMid_Gain = app.LowMidEditField.Value;
           app.Mid_Gain = app.MidEditField.Value;
           app.UpperMid_Gain = app.UpMidEditField.Value;
           app.High_Gain = app.HighEditField.Value;
           
           app.SuperLowSlider.Value = app.SuperLow_Gain;
           app.LowSlider.Value = app.Low_Gain;
           app.LowMidrangeSlider.Value = app.LowerMid_Gain;
           app.MidrangeSlider.Value = app.Mid_Gain;
           app.UpperMidrangeSlider.Value = app.UpperMid_Gain;
           app.HighSlider.Value = app.High_Gain;
           Gainchange(app)
           DrawOutput(app)
            
        end

        % Size changed function: UIFigure
        function DrawOutput(app, event)
           if app.mod == "1"
            [app.y2, app.Fs2] = audioread(fullfile(app.path,app.file));
            

            h2=fir1(100,[20/app.Fs2 60/app.Fs2],'low'); 
            h3=fir1(100,[60/app.Fs2 200/app.Fs2],'low');
            h4=fir1(100,[200/app.Fs2 600/app.Fs2],'bandpass'); 
            h5=fir1(100,[600/app.Fs2 3000/app.Fs2],'bandpass');
            h6=fir1(100,[3000/app.Fs1 7000/app.Fs1],'bandpass');
            h7=fir1(100,7000/app.Fs2,'high'); 

            app.yx2=filter(h2,1,app.y2); 
            app.yx3=filter(h3,1,app.y2); 
            app.yx4=filter(h4,1,app.y2); 
            app.yx5=filter(h5,1,app.y2); 
            app.yx6=filter(h6,1,app.y2); 
            app.yx7=filter(h7,1,app.y2); 
            
            app.y2=app.yx2*app.SuperLowSlider.Value+app.yx3*app.LowSlider.Value+app.yx4*app.LowMidrangeSlider.Value+app.yx5*app.MidrangeSlider.Value+app.yx6*app.UpperMidrangeSlider.Value+app.yx7*app.HighSlider.Value;
            app.mod_player = audioplayer(app.y2, app.Fs2);
       
      
            plot(app.UIAxes_2, app.y2);
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @DrawOutput, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, ' ')
            xlabel(app.UIAxes3, 'Frequency')
            ylabel(app.UIAxes3, 'Gain')
            app.UIAxes3.YTick = [-12 -6 0 6 12];
            app.UIAxes3.YTickLabel = {'-12'; '-6'; '0'; '6'; '12'};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Waveform')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Magnitude')
            app.UIAxes.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Output Waveform')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Magnitude')
            app.Low250HzLabel = uilabel(app.UIFigure);
            app.Low250HzLabel.Text = 'Super Low';
            app.SuperLowSlider = uislider(app.UIFigure);
            app.SuperLowSlider.Limits = [-12 12];
            app.SuperLowSlider.MajorTicks = [];
            app.SuperLowSlider.MajorTickLabels = {''};
            app.SuperLowSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.SuperLowSlider.MinorTicks = [];
            app.SuperLowEditField = uieditfield(app.UIFigure, 'numeric');
            app.SuperLowEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.LowEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.LowMidEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowMidEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.MidEditField = uieditfield(app.UIFigure, 'numeric');
            app.MidEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.UpMidEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpMidEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.HighEditField = uieditfield(app.UIFigure, 'numeric');
            app.HighEditField.ValueChangedFcn = createCallbackFcn(app, @SuperLowEditFieldValueChanged, true);
            app.Band250500HzLabel = uilabel(app.UIFigure);
            app.Band250500HzLabel.Text = 'Low';
            app.LowSlider = uislider(app.UIFigure);
            app.LowSlider.Limits = [-12 12];
            app.LowSlider.MajorTicks = [];
            app.LowSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.LowSlider.MinorTicks = [];
            app.Band052kHzLabel = uilabel(app.UIFigure);
            app.Band052kHzLabel.Text = 'Low Mid-range';
            app.LowMidrangeSlider = uislider(app.UIFigure);
            app.LowMidrangeSlider.Limits = [-12 12];
            app.LowMidrangeSlider.MajorTicks = [];
            app.LowMidrangeSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.LowMidrangeSlider.MinorTicks = [];
            app.Band24kHzLabel = uilabel(app.UIFigure);
            app.Band24kHzLabel.Text = 'Mid-range';
            app.MidrangeSlider = uislider(app.UIFigure);
            app.MidrangeSlider.Limits = [-12 12];
            app.MidrangeSlider.MajorTicks = [];
            app.MidrangeSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.MidrangeSlider.MinorTicks = [];
            app.UpperMidrangeSliderLabel = uilabel(app.UIFigure);
            app.UpperMidrangeSliderLabel.Text = 'Upper Mid-range';
            app.UpperMidrangeSlider = uislider(app.UIFigure);
            app.UpperMidrangeSlider.Limits = [-12 12];
            app.UpperMidrangeSlider.MajorTicks = [];
            app.UpperMidrangeSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.UpperMidrangeSlider.MinorTicks = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FileNameArea = uitextarea(app.UIFigure);
            app.FileNameArea.ValueChangedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.MATLABGUIAUDIOEQUALIZERLabel = uilabel(app.UIFigure);
            app.MATLABGUIAUDIOEQUALIZERLabel.Text = 'MATLAB GUI AUDIO EQUALIZER';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseResumeButton = uibutton(app.UIFigure, 'push');
            app.PauseResumeButton.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButtonPushed, true);
            app.PauseResumeButton.Text = 'Pause/Resume';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PlayButton_2 = uibutton(app.UIFigure, 'push');
            app.PlayButton_2.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_2Pushed, true);
            app.PlayButton_2.Text = 'Play';
            app.PauseResumeButton_2 = uibutton(app.UIFigure, 'push');
            app.PauseResumeButton_2.ButtonPushedFcn = createCallbackFcn(app, @PauseResumeButton_2Pushed, true);
            app.PauseResumeButton_2.Text = 'Pause/Resume';
            app.StopButton_2 = uibutton(app.UIFigure, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.HighSliderLabel = uilabel(app.UIFigure);
            app.HighSliderLabel.Text = 'High';
            app.HighSlider = uislider(app.UIFigure);
            app.HighSlider.Limits = [-12 12];
            app.HighSlider.MajorTicks = [];
            app.HighSlider.ValueChangedFcn = createCallbackFcn(app, @SuperLowSliderValueChanged, true);
            app.HighSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Equalizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Gainchange)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end